classdef AFTF_processing < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AFTFDataPostProcesserUIFigure   matlab.ui.Figure
        FanDimensionsPanel              matlab.ui.container.Panel
        XButton                         matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        TextArea                        matlab.ui.control.TextArea
        graphPanel                      matlab.ui.container.Panel
        EnterFilePathEditFieldLabel     matlab.ui.control.Label
        EnterFilePathEditField          matlab.ui.control.EditField
        EnterNumberofDataPointsEditFieldLabel  matlab.ui.control.Label
        EnterNumberofDataPointsEditField  matlab.ui.control.NumericEditField
        TestDateDatePickerLabel         matlab.ui.control.Label
        TestDateDatePicker              matlab.ui.control.DatePicker
        TestSeriesEditFieldLabel        matlab.ui.control.Label
        TestSeriesEditField             matlab.ui.control.EditField
        TestIdentificationNumberLabel   matlab.ui.control.Label
        TestIdentificationNumberEditField  matlab.ui.control.EditField
        FanRPMEditFieldLabel            matlab.ui.control.Label
        FanRPMEditField                 matlab.ui.control.NumericEditField
        RPMTestNumberEditFieldLabel     matlab.ui.control.Label
        RPMTestNumberEditField          matlab.ui.control.EditField
        TestFanVariableFrequencyDriveSettingsPanel  matlab.ui.container.Panel
        FrequencyHzEditFieldLabel       matlab.ui.control.Label
        FrequencyHzEditField            matlab.ui.control.NumericEditField
        CurrentAEditFieldLabel          matlab.ui.control.Label
        CurrentAEditField               matlab.ui.control.NumericEditField
        VoltageVACEditFieldLabel        matlab.ui.control.Label
        VoltageVACEditField             matlab.ui.control.NumericEditField
        ShowFanPerformanceCurveButton   matlab.ui.control.Button
        GenerateReportButton            matlab.ui.control.Button
        TestSetUpDropDownLabel          matlab.ui.control.Label
        TestSetUpDropDown               matlab.ui.control.DropDown
        AdditionalNotesTextAreaLabel    matlab.ui.control.Label
        AdditionalNotesTextArea         matlab.ui.control.TextArea
        BrowseFIlesButton               matlab.ui.control.Button
        setUpsButtons                   matlab.ui.container.Panel
        AddNewTestSetUpButton           matlab.ui.control.Button
        DeleteSelectedTestSetUpButton   matlab.ui.control.Button
        EditSelectedTestSetUpButton     matlab.ui.control.Button
        addTestSetUpPanel               matlab.ui.container.TabGroup
        TestDimensionsTab               matlab.ui.container.Tab
        FanTypeNameEditField_2Label     matlab.ui.control.Label
        FanTypeNameEditField            matlab.ui.control.EditField
        TransitionDuctingOutletLengthainEditField_2Label  matlab.ui.control.Label
        TransitionDuctingOutletLengthainEditField  matlab.ui.control.NumericEditField
        TransitionDuctingOutletWidthbinEditField_2Label  matlab.ui.control.Label
        TransitionDuctingOutletWidthbinEditField  matlab.ui.control.NumericEditField
        EnterButton                     matlab.ui.control.Button
        XButton_2                       matlab.ui.control.Button
        TextArea_2                      matlab.ui.control.TextArea
        ChamberDiameterD5inEditFieldLabel  matlab.ui.control.Label
        ChamberDiameterD5inEditField    matlab.ui.control.NumericEditField
        Station6NozzleTypeEditField_2Label  matlab.ui.control.Label
        Station6NozzleTypeEditField     matlab.ui.control.EditField
        KnownFaultySensorDataandAdditionalNotesTextArea_2Label  matlab.ui.control.Label
        KnownFaultySensorDataandAdditionalNotesTextArea  matlab.ui.control.TextArea
        TestPersonnelLabel              matlab.ui.control.Label
        TestPersonnelTextArea           matlab.ui.control.TextArea
        TestFanTransitionDuctingTypeDimensionsTextArea_2Label  matlab.ui.control.Label
        TestFanTransitionDuctingTypeDimensionsTextArea  matlab.ui.control.TextArea
        TestUnitEditFieldLabel          matlab.ui.control.Label
        TestUnitEditField               matlab.ui.control.EditField
        NozzleLDRatioDropDownLabel      matlab.ui.control.Label
        NozzleLDRatioDropDown           matlab.ui.control.DropDown
        NozzleSetUpTab                  matlab.ui.container.Tab
        backgroundnozzle                matlab.ui.control.Lamp
        nozzle1                         matlab.ui.control.Lamp
        nozzle2                         matlab.ui.control.Lamp
        nozzle3                         matlab.ui.control.Lamp
        nozzle4                         matlab.ui.control.Lamp
        nozzle5                         matlab.ui.control.Lamp
        nozzle6                         matlab.ui.control.Lamp
        nozzle7                         matlab.ui.control.Lamp
        nozzle8                         matlab.ui.control.Lamp
        nozzle9                         matlab.ui.control.Lamp
        Nozzle1DiameterDropDown         matlab.ui.control.DropDown
        Nozzle2DiameterDropDown         matlab.ui.control.DropDown
        Nozzle3DiameterDropDown         matlab.ui.control.DropDown
        Nozzle4DiameterDropDown         matlab.ui.control.DropDown
        Nozzle5DiameterDropDown         matlab.ui.control.DropDown
        Nozzle6DiameterDropDown         matlab.ui.control.DropDown
        Nozzle7DiameterDropDown         matlab.ui.control.DropDown
        Nozzle8DiameterDropDown         matlab.ui.control.DropDown
        Nozzle9DiameterDropDown         matlab.ui.control.DropDown
        XButton_4                       matlab.ui.control.Button
        EnterButton_3                   matlab.ui.control.Button
        editSetUpPanel                  matlab.ui.container.TabGroup
        TestDimensionsTab_2             matlab.ui.container.Tab
        FanTypeNameEditFieldLabel       matlab.ui.control.Label
        FanTypeNameEditField_2          matlab.ui.control.EditField
        ChamberDiameterD5inEditField_2Label  matlab.ui.control.Label
        ChamberDiameterD5inEditField_2  matlab.ui.control.NumericEditField
        TransitionDuctingOutletLengthainEditFieldLabel  matlab.ui.control.Label
        TransitionDuctingOutletLengthainEditField_2  matlab.ui.control.NumericEditField
        TransitionDuctingOutletWidthbinEditFieldLabel  matlab.ui.control.Label
        TransitionDuctingOutletWidthbinEditField_2  matlab.ui.control.NumericEditField
        EnterButton_2                   matlab.ui.control.Button
        XButton_5                       matlab.ui.control.Button
        TextArea_3                      matlab.ui.control.TextArea
        Station6NozzleTypeEditFieldLabel  matlab.ui.control.Label
        Station6NozzleTypeEditField_2   matlab.ui.control.EditField
        TestFanTransitionDuctingTypeDimensionsTextAreaLabel  matlab.ui.control.Label
        TestFanTransitionDuctingTypeDimensionsTextArea_2  matlab.ui.control.TextArea
        KnownFaultySensorDataandAdditionalNotesTextAreaLabel  matlab.ui.control.Label
        KnownFaultySensorDataandAdditionalNotesTextArea_2  matlab.ui.control.TextArea
        NozzleLDRatioDropDown_2Label    matlab.ui.control.Label
        NozzleLDRatioDropDown_2         matlab.ui.control.DropDown
        TestPersonnelTextArea_2Label    matlab.ui.control.Label
        TestPersonnelTextArea_2         matlab.ui.control.TextArea
        TestUnitEditField_2Label        matlab.ui.control.Label
        TestUnitEditField_2             matlab.ui.control.EditField
        NozzleSetUpTab_2                matlab.ui.container.Tab
        Lamp_11                         matlab.ui.control.Lamp
        Lamp_12                         matlab.ui.control.Lamp
        Lamp_13                         matlab.ui.control.Lamp
        Lamp_14                         matlab.ui.control.Lamp
        Lamp_15                         matlab.ui.control.Lamp
        Lamp_16                         matlab.ui.control.Lamp
        Lamp_17                         matlab.ui.control.Lamp
        Lamp_18                         matlab.ui.control.Lamp
        Lamp_19                         matlab.ui.control.Lamp
        Lamp_20                         matlab.ui.control.Lamp
        Nozzle1DiameterDropDown_2       matlab.ui.control.DropDown
        Nozzle2DiameterDropDown_2       matlab.ui.control.DropDown
        Nozzle3DiameterDropDown_2       matlab.ui.control.DropDown
        Nozzle4DiameterDropDown_2       matlab.ui.control.DropDown
        Nozzle5DiameterDropDown_2       matlab.ui.control.DropDown
        Nozzle6DiameterDropDown_2       matlab.ui.control.DropDown
        Nozzle7DiameterDropDown_2       matlab.ui.control.DropDown
        Nozzle8DiameterDropDown_2       matlab.ui.control.DropDown
        Nozzle9DiameterDropDown_2       matlab.ui.control.DropDown
        XButton_6                       matlab.ui.control.Button
        EnterButton_4                   matlab.ui.control.Button
    end


    properties (Access = public)
        var=load('testSetUps.mat');
        RunNumber,targetDiskPos_percent,targetConePos_percent,NominalNfan_RPM,Nmotor_RPM,Nfan_instantaneous_RPM,Nfan_RPM,Tq_lb,Ps2L_wg,deltaPs2_5_2L_wg,Ps2_5L_wg,Ps7L_wg,Ps5L_wg,deltaPs5_6L_wg,Ps6L_wg,T2_5aL_F,T2_5bL_F,T2_5cL_F,T7aL_F,T7bL_F,T7cL_F,Trtd7L_F,T5aL_F,T5bL_F,T5cL_F,Trtd5L_F,T6aL_F,T6bL_F,T6cL_F,Trtd6L_F,Pba_mbar,Pbb_mbar,Pbc_mbar,Ps0_wg,T0a_F,T0b_F,T0c_F,Tdewa_F,Tdewb_F,Tdewc_F,Rha_percent,Rhb_percent,Rhc_percent,DLC_in,DL0_in,DL45_in,DL90_in,DL135_in,DL180_in,DL225_in,DL270_in,DL315_in,EFanPitch_deg,DiskPos_percent,ConePos_percent
        setUpName, index;
        n;
        testSetUpList, namesList;
        menu_timer;
    end

    methods (Access = private)
        
        function update_menu(app, ~, ~)
            app.var=load('testSetUps.mat');
            options=cellstr(getNamesList(app));
            blank = 'Select Set Up';
            options = [blank, options];
            app.TestSetUpDropDown.Items = options;
        end
        
    end

    methods (Access = public)
    
        function testSetUpList = getTestSetUpList(app)
            testSetUpList = app.var.testSetUpList;
        end
        function namesList = getNamesList(app)
            namesList = app.var.namesList;
        end
       
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
              app.menu_timer = timer('ExecutionMode', 'fixedRate','Period', 1, 'TimerFcn', @app.update_menu);
              start(app.menu_timer);
        end

        % Button pushed function: ShowFanPerformanceCurveButton
        function generateCurve_app(app, event)
            value = app.TestSetUpDropDown.Value;
            filePath = app.EnterFilePathEditField.Value;
            filePath = replace(filePath, '"' , '');
            try
                app.setUpName = app.TestSetUpDropDown.Value;
                app.index = readSetUp(app.setUpName);
                list = getTestSetUpList(app);
                [Q, P] = findFinalValues(app.T2_5aL_F, app.T2_5bL_F, app.T2_5cL_F, app.T7aL_F, app.T7bL_F, app.T7cL_F, app.Trtd7L_F, app.T5aL_F, app.T5bL_F, app.T5cL_F, app.Trtd5L_F, app.T6aL_F, app.T6bL_F, app.T6cL_F, app.Trtd6L_F, app.Pba_mbar, app.Pbb_mbar, app.Pbc_mbar, app.Ps0_wg, app.T0a_F, app.T0b_F, app.T0c_F, app.Tdewa_F, app.Tdewb_F, app.Tdewc_F, app.Rha_percent, app.Rhb_percent, app.Rhc_percent, list(app.index).D5, list(app.index).D6, list(app.index).a, list(app.index).b, app.Ps7L_wg, app.Ps6L_wg, app.Ps5L_wg, app.Ps2L_wg, app.deltaPs5_6L_wg, app.n, list(app.index).LD);
                [xData, yData] = prepareCurveData( Q, P );

                % Set up fittype and options.
                ft = fittype( 'smoothingspline' );
            
                % Fit model to data.
                [fitresult, gof] = fit( xData, yData, ft );
            
                % Plot fit with data.
                figure( 'Name', 'Fan Performance Curve' );
                h = plot( fitresult, xData, yData );
                legend('P vs Q', 'Fit with Smoothing Spline');
                grid on
            
                title('Fan Characteristic Performance Curve');
                xlabel('Fan Airflow Rate, Q (ft^3 / min)');  
                ylabel('Fan Static Pressure, P (in. wg.)');
    
            catch
                if strcmp(value, 'Select Set Up') 
                    uialert(app.AFTFDataPostProcesserUIFigure, 'Please select a valid test set up', 'Invalid Test Set Up');
                elseif ~exist(filePath, 'file')
                    uialert(app.AFTFDataPostProcesserUIFigure, 'Please enter a valid file path', 'Invalid File Path');
                end
            end
        end

        % Value changed function: EnterFilePathEditField, 
        % EnterNumberofDataPointsEditField
        function import(app, event)
            filePath = app.EnterFilePathEditField.Value;
            filePath = replace(filePath, '"' , '');
            app.n = app.EnterNumberofDataPointsEditField.Value;
            try 
                [app.RunNumber,app.targetDiskPos_percent,app.targetConePos_percent,app.NominalNfan_RPM,app.Nmotor_RPM, app.Nfan_instantaneous_RPM,app.Nfan_RPM,app.Tq_lb,app.Ps2L_wg,app.deltaPs2_5_2L_wg,app.Ps2_5L_wg,app.Ps7L_wg,app.Ps5L_wg,app.deltaPs5_6L_wg,app.Ps6L_wg,app.T2_5aL_F,app.T2_5bL_F,app.T2_5cL_F,app.T7aL_F,app.T7bL_F,app.T7cL_F,app.Trtd7L_F,app.T5aL_F,app.T5bL_F,app.T5cL_F,app.Trtd5L_F,app.T6aL_F,app.T6bL_F,app.T6cL_F,app.Trtd6L_F,app.Pba_mbar,app.Pbb_mbar,app.Pbc_mbar,app.Ps0_wg,app.T0a_F,app.T0b_F,app.T0c_F,app.Tdewa_F,app.Tdewb_F,app.Tdewc_F,app.Rha_percent,app.Rhb_percent,app.Rhc_percent,app.DLC_in,app.DL0_in,app.DL45_in,app.DL90_in,app.DL135_in,app.DL180_in,app.DL225_in,app.DL270_in,app.DL315_in,app.EFanPitch_deg,app.DiskPos_percent,app.ConePos_percent] = importFanData(filePath, 1, app.n);
            catch
                uialert(app.AFTFDataPostProcesserUIFigure, 'Please enter a valid file path', 'Invalid File Path');
            end
        end

        % Button pushed function: EnterButton, EnterButton_3
        function addNew(app, event)
            D6arr = zeros(1, 9);
            app.namesList = getNamesList(app);
            nozzleArray = {app.Nozzle1DiameterDropDown.Value, app.Nozzle2DiameterDropDown.Value, app.Nozzle3DiameterDropDown.Value, app.Nozzle4DiameterDropDown.Value, app.Nozzle5DiameterDropDown.Value, app.Nozzle6DiameterDropDown.Value, app.Nozzle7DiameterDropDown.Value, app.Nozzle8DiameterDropDown.Value, app.Nozzle9DiameterDropDown.Value};
            for i=1:9
                if ~strcmp( 'Not Used', nozzleArray(i))
                    D6arr(i)=str2double(nozzleArray(i));
                end
            end
            %checks for duplicates of test set ups
            y=0;
            if isempty(app.namesList)
                x = ismember(app.FanTypeNameEditField.Value, app.namesList);
                y=sum(x);
            end
            if y==0
                addNewTestSetUp(app.FanTypeNameEditField.Value, app.TestUnitEditField.Value, app.ChamberDiameterD5inEditField.Value, app.Station6NozzleTypeEditField.Value, D6arr, app.NozzleLDRatioDropDown.Value, app.TransitionDuctingOutletLengthainEditField.Value, app.TransitionDuctingOutletWidthbinEditField.Value, app.TestPersonnelTextArea.Value, app.TestFanTransitionDuctingTypeDimensionsTextArea.Value, app.KnownFaultySensorDataandAdditionalNotesTextArea.Value);
                app.FanTypeNameEditField.Value='';
                app.TestUnitEditField.Value='';
                app.Station6NozzleTypeEditField.Value='';
                app.TestPersonnelTextArea.Value='';
                app.TestFanTransitionDuctingTypeDimensionsTextArea.Value='';
                app.KnownFaultySensorDataandAdditionalNotesTextArea.Value='';
                app.ChamberDiameterD5inEditField.Value = 0;
                app.NozzleLDRatioDropDown.Value = '0.5';
                app.TransitionDuctingOutletLengthainEditField.Value=0;
                app.TransitionDuctingOutletWidthbinEditField.Value=0;
                 app.addTestSetUpPanel.Visible = 'off';
            else 
                uialert(app.AFTFDataPostProcesserUIFigure, 'Test set up with that name already exists', 'Duplicate Test Set Up');
            end
        end

        % Button pushed function: AddNewTestSetUpButton
        function showTestSetUpPanel(app, event)
            app.addTestSetUpPanel.Visible = 'on';
        end

        % Close request function: AFTFDataPostProcesserUIFigure
        function AFTFDataPostProcesserUIFigureCloseRequest(app, event)
                stop(app.menu_timer);
                delete(app.menu_timer);
                delete(app);
        end

        % Button pushed function: DeleteSelectedTestSetUpButton
        function deleteTestSetUp_app(app, event)
            value = app.TestSetUpDropDown.Value;
            app.index = readSetUp(value);
            if strcmp(value, 'Select Set Up')
                uialert(app.AFTFDataPostProcesserUIFigure, 'Please select a valid test set up', 'Invalid Test Set Up');
            else
                   confirmation = uiconfirm(app.AFTFDataPostProcesserUIFigure, 'Confirm deletion of selected test set up?', 'Confirm Delete');
                   if strcmp(confirmation, 'OK')
                       deleteTestSetUp(app.index);
                   end
            end
        end

        % Button pushed function: XButton_2, XButton_4
        function hideTestSetUp(app, event)
            app.addTestSetUpPanel.Visible = 'off';
        end

        % Button pushed function: EditSelectedTestSetUpButton
        function showEditPanel(app, event)
            value = app.TestSetUpDropDown.Value;
            if strcmp(value, 'Select Set Up')
                uialert(app.AFTFDataPostProcesserUIFigure, 'Please select a valid test set up', 'Invalid Test Set Up');
            else
                app.editSetUpPanel.Visible = 'on';
                list = getTestSetUpList(app);
                app.FanTypeNameEditField_2.Value = list(1, app.index).name;
                app.ChamberDiameterD5inEditField_2.Value = list(1, app.index).D5;
                app.TransitionDuctingOutletLengthainEditField_2.Value = list(1, app.index).a;
                app.TransitionDuctingOutletWidthbinEditField_2.Value = list(1, app.index).b;
                app.Station6NozzleTypeEditField_2.Value = list(1, app.index).D6Type;
                app.TestFanTransitionDuctingTypeDimensionsTextArea_2.Value = list(1, app.index).transitionDim;
                app.KnownFaultySensorDataandAdditionalNotesTextArea_2.Value = list(1, app.index).notes;
                app.NozzleLDRatioDropDown_2.Value = list(1, app.index).LD;
                app.TestPersonnelTextArea_2.Value = list(1, app.index).testPersonnel;
                app.TestUnitEditField_2.Value = list(1, app.index).testUnit;
                
                if list(1, app.index).D6(1) == 0
                    app.Nozzle1DiameterDropDown_2.Value = 'Not Used';
                else
                    app.Nozzle1DiameterDropDown_2.Value = num2str(list(1, app.index).D6(1));
                end
                
                if list(1, app.index).D6(2) == 0
                    app.Nozzle2DiameterDropDown_2.Value = 'Not Used';
                else
                    app.Nozzle2DiameterDropDown_2.Value = num2str(list(1, app.index).D6(1));
                end
                
                if list(1, app.index).D6(3) == 0
                    app.Nozzle3DiameterDropDown_2.Value = 'Not Used';
                else
                    app.Nozzle3DiameterDropDown_2.Value = num2str(list(1, app.index).D6(3));
                end
                                
                if list(1, app.index).D6(4) == 0
                    app.Nozzle4DiameterDropDown_2.Value = 'Not Used';
                else
                    app.Nozzle4DiameterDropDown_2.Value = num2str(list(1, app.index).D6(4));
                end
                if list(1, app.index).D6(5) == 0
                    app.Nozzle5DiameterDropDown_2.Value = 'Not Used';
                else
                    app.Nozzle5DiameterDropDown_2.Value = num2str(list(1, app.index).D6(5));
                end
                if list(1, app.index).D6(6) == 0
                    app.Nozzle6DiameterDropDown_2.Value = 'Not Used';
                else
                    app.Nozzle6DiameterDropDown_2.Value = num2str(list(1, app.index).D6(6));
                end
                if list(1, app.index).D6(7) == 0
                    app.Nozzle7DiameterDropDown_2.Value = 'Not Used';
                else
                    app.Nozzle7DiameterDropDown_2.Value = num2str(list(1, app.index).D6(7));
                end
                if list(1, app.index).D6(8) == 0
                    app.Nozzle8DiameterDropDown_2.Value = 'Not Used';
                else
                    app.Nozzle8DiameterDropDown_2.Value = num2str(list(1, app.index).D6(8));
                end
                if list(1, app.index).D6(9) == 0
                    app.Nozzle9DiameterDropDown_2.Value = 'Not Used';
                else
                    app.Nozzle9DiameterDropDown_2.Value = num2str(list(1, app.index).D6(9));
                end
            end
        end

        % Button pushed function: EnterButton_2, EnterButton_4
        function editSetUp(app, event)
            app.setUpName = app.TestSetUpDropDown.Value;
            app.index = readSetUp(app.setUpName);
            D6arr = zeros(1, 9);
            app.namesList = getNamesList(app);
            nozzleArray = {app.Nozzle1DiameterDropDown_2.Value, app.Nozzle2DiameterDropDown_2.Value, app.Nozzle3DiameterDropDown_2.Value, app.Nozzle4DiameterDropDown_2.Value, app.Nozzle5DiameterDropDown_2.Value, app.Nozzle6DiameterDropDown_2.Value, app.Nozzle7DiameterDropDown_2.Value, app.Nozzle8DiameterDropDown_2.Value, app.Nozzle9DiameterDropDown_2.Value};
            for i=1:9
                if ~strcmp( 'Not Used', nozzleArray(i))
                    D6arr(i)=str2double(nozzleArray(i));
                end
            end
            editTestSetUp(app.FanTypeNameEditField_2.Value, app.TestUnitEditField_2.Value, app.ChamberDiameterD5inEditField_2.Value, app.Station6NozzleTypeEditField_2.Value, D6arr, app.NozzleLDRatioDropDown_2.Value, app.TransitionDuctingOutletLengthainEditField_2.Value, app.TransitionDuctingOutletWidthbinEditField_2.Value, app.TestPersonnelTextArea_2.Value, app.TestFanTransitionDuctingTypeDimensionsTextArea_2.Value, app.KnownFaultySensorDataandAdditionalNotesTextArea_2.Value, app.index);
            app.editSetUpPanel.Visible = 'off';
        end

        % Button pushed function: XButton_5, XButton_6
        function hideEditPanel(app, event)
            app.editSetUpPanel.Visible = 'off';
        end

        % Value changed function: TestSetUpDropDown
        function readSetUp_app(app, event)
            if ~strcmp(app.TestSetUpDropDown.Value, 'Select Set Up')
                app.setUpName = app.TestSetUpDropDown.Value;
                app.index = readSetUp(app.setUpName);
            end
        end

        % Button pushed function: GenerateReportButton
        function generateReport(app, event)
            app.setUpName = app.TestSetUpDropDown.Value;
            filePath = app.EnterFilePathEditField.Value;
            filePath = replace(filePath, '"' , ''); 
            try   
                 app.index = readSetUp(app.setUpName);
                 list = getTestSetUpList(app); 
                 %add new values to be reported to list in []
                 [Q, P, Q5] = findFinalValues(app.T2_5aL_F, app.T2_5bL_F, app.T2_5cL_F, app.T7aL_F, app.T7bL_F, app.T7cL_F, app.Trtd7L_F, app.T5aL_F, app.T5bL_F, app.T5cL_F, app.Trtd5L_F, app.T6aL_F, app.T6bL_F, app.T6cL_F, app.Trtd6L_F, app.Pba_mbar, app.Pbb_mbar, app.Pbc_mbar, app.Ps0_wg, app.T0a_F, app.T0b_F, app.T0c_F, app.Tdewa_F, app.Tdewb_F, app.Tdewc_F, app.Rha_percent, app.Rhb_percent, app.Rhc_percent, list(app.index).D5, list(app.index).D6, list(app.index).a, list(app.index).b, app.Ps7L_wg, app.Ps6L_wg, app.Ps5L_wg, app.Ps2L_wg, app.deltaPs5_6L_wg, app.n, app.index); 
                 date = app.TestDateDatePicker.Value;
                 testSeries = app.TestSeriesEditField.Value;
                 testID = app.TestIdentificationNumberEditField.Value;
                 RPM = app.FanRPMEditField.Value;
                 RPMtestNum = app.RPMTestNumberEditField.Value;
                 freq = app.FrequencyHzEditField.Value;
                 current = app.CurrentAEditField.Value;
                 voltage = app.VoltageVACEditField.Value;
                 notes = app.AdditionalNotesTextArea.Value;
                 readSetUp_app(app);
                 currentIndex = app.index;
                 numPoints = app.n;
                    save('reportInfo', 'date', 'testSeries', 'testID', 'RPM', 'RPMtestNum', 'freq', 'current', ...
                     'voltage','notes', 'currentIndex', 'Q', 'P', 'Q5', 'numPoints', 'filePath');
                 createReport;
            catch
                if strcmp(app.setUpName, 'Select Set Up') 
                    uialert(app.AFTFDataPostProcesserUIFigure, 'Please select a valid test set up', 'Invalid Test Set Up');
                elseif ~exist(filePath, 'file')
                    uialert(app.AFTFDataPostProcesserUIFigure, 'Please enter a valid file path', 'Invalid File Path');
                end
            end
        end

        % Button pushed function: BrowseFIlesButton
        function browseFile(app, event)
            [file, path] = uigetfile('*.txt');
             app.AFTFDataPostProcesserUIFigure.Visible = 'off';
             app.AFTFDataPostProcesserUIFigure.Visible = 'on';
            app.EnterFilePathEditField.Value = fullfile(path, file);
            app.n = app.EnterNumberofDataPointsEditField.Value;
            try 
                [app.RunNumber,app.targetDiskPos_percent,app.targetConePos_percent,app.NominalNfan_RPM,app.Nmotor_RPM, app.Nfan_instantaneous_RPM,app.Nfan_RPM,app.Tq_lb,app.Ps2L_wg,app.deltaPs2_5_2L_wg,app.Ps2_5L_wg,app.Ps7L_wg,app.Ps5L_wg,app.deltaPs5_6L_wg,app.Ps6L_wg,app.T2_5aL_F,app.T2_5bL_F,app.T2_5cL_F,app.T7aL_F,app.T7bL_F,app.T7cL_F,app.Trtd7L_F,app.T5aL_F,app.T5bL_F,app.T5cL_F,app.Trtd5L_F,app.T6aL_F,app.T6bL_F,app.T6cL_F,app.Trtd6L_F,app.Pba_mbar,app.Pbb_mbar,app.Pbc_mbar,app.Ps0_wg,app.T0a_F,app.T0b_F,app.T0c_F,app.Tdewa_F,app.Tdewb_F,app.Tdewc_F,app.Rha_percent,app.Rhb_percent,app.Rhc_percent,app.DLC_in,app.DL0_in,app.DL45_in,app.DL90_in,app.DL135_in,app.DL180_in,app.DL225_in,app.DL270_in,app.DL315_in,app.EFanPitch_deg,app.DiskPos_percent,app.ConePos_percent] = importFanData(fullfile(path, file), 1, app.n);
            catch
                uialert(app.AFTFDataPostProcesserUIFigure, 'Please enter a valid file path', 'Invalid File Path');
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AFTFDataPostProcesserUIFigure = uifigure;
            app.AFTFDataPostProcesserUIFigure.Name = 'AFTF Data Post Processer';
            app.AFTFDataPostProcesserUIFigure.CloseRequestFcn = createCallbackFcn(app, @AFTFDataPostProcesserUIFigureCloseRequest, true);
            app.FanDimensionsPanel = uipanel(app.AFTFDataPostProcesserUIFigure);
            app.FanDimensionsPanel.Title = 'Fan Dimensions';
            app.FanDimensionsPanel.Visible = 'off';
            app.XButton = uibutton(app.FanDimensionsPanel, 'push');
            app.XButton.Text = 'X';
            app.Label = uilabel(app.FanDimensionsPanel);
            app.Label.Text = '';
            app.TextArea = uitextarea(app.FanDimensionsPanel);
            app.graphPanel = uipanel(app.AFTFDataPostProcesserUIFigure);
            app.EnterFilePathEditFieldLabel = uilabel(app.graphPanel);
            app.EnterFilePathEditFieldLabel.Text = 'Enter File Path:';
            app.EnterFilePathEditField = uieditfield(app.graphPanel, 'text');
            app.EnterFilePathEditField.ValueChangedFcn = createCallbackFcn(app, @import, true);
            app.EnterNumberofDataPointsEditFieldLabel = uilabel(app.graphPanel);
            app.EnterNumberofDataPointsEditFieldLabel.Text = 'Enter Number of Data Points';
            app.EnterNumberofDataPointsEditField = uieditfield(app.graphPanel, 'numeric');
            app.EnterNumberofDataPointsEditField.ValueChangedFcn = createCallbackFcn(app, @import, true);
            app.EnterNumberofDataPointsEditField.Value = 35;
            app.TestDateDatePickerLabel = uilabel(app.graphPanel);
            app.TestDateDatePickerLabel.Text = 'Test Date';
            app.TestDateDatePicker = uidatepicker(app.graphPanel);
            app.TestSeriesEditFieldLabel = uilabel(app.graphPanel);
            app.TestSeriesEditFieldLabel.Text = 'Test Series';
            app.TestSeriesEditField = uieditfield(app.graphPanel, 'text');
            app.TestIdentificationNumberLabel = uilabel(app.graphPanel);
            app.TestIdentificationNumberLabel.Text = 'Test Identification Number';
            app.TestIdentificationNumberEditField = uieditfield(app.graphPanel, 'text');
            app.FanRPMEditFieldLabel = uilabel(app.graphPanel);
            app.FanRPMEditFieldLabel.Text = 'Fan RPM';
            app.FanRPMEditField = uieditfield(app.graphPanel, 'numeric');
            app.RPMTestNumberEditFieldLabel = uilabel(app.graphPanel);
            app.RPMTestNumberEditFieldLabel.Text = 'RPM Test Number';
            app.RPMTestNumberEditField = uieditfield(app.graphPanel, 'text');
            app.TestFanVariableFrequencyDriveSettingsPanel = uipanel(app.graphPanel);
            app.TestFanVariableFrequencyDriveSettingsPanel.Title = 'Test Fan Variable Frequency Drive Settings';
            app.FrequencyHzEditFieldLabel = uilabel(app.TestFanVariableFrequencyDriveSettingsPanel);
            app.FrequencyHzEditFieldLabel.Text = 'Frequency (Hz)';
            app.FrequencyHzEditField = uieditfield(app.TestFanVariableFrequencyDriveSettingsPanel, 'numeric');
            app.CurrentAEditFieldLabel = uilabel(app.TestFanVariableFrequencyDriveSettingsPanel);
            app.CurrentAEditFieldLabel.Text = 'Current (A)';
            app.CurrentAEditField = uieditfield(app.TestFanVariableFrequencyDriveSettingsPanel, 'numeric');
            app.VoltageVACEditFieldLabel = uilabel(app.TestFanVariableFrequencyDriveSettingsPanel);
            app.VoltageVACEditFieldLabel.Text = 'Voltage (VAC)';
            app.VoltageVACEditField = uieditfield(app.TestFanVariableFrequencyDriveSettingsPanel, 'numeric');
            app.ShowFanPerformanceCurveButton = uibutton(app.graphPanel, 'push');
            app.ShowFanPerformanceCurveButton.ButtonPushedFcn = createCallbackFcn(app, @generateCurve_app, true);
            app.ShowFanPerformanceCurveButton.Text = 'Show Fan Performance Curve';
            app.GenerateReportButton = uibutton(app.graphPanel, 'push');
            app.GenerateReportButton.ButtonPushedFcn = createCallbackFcn(app, @generateReport, true);
            app.GenerateReportButton.Text = 'Generate Report';
            app.TestSetUpDropDownLabel = uilabel(app.graphPanel);
            app.TestSetUpDropDownLabel.Text = 'Test Set Up';
            app.TestSetUpDropDown = uidropdown(app.graphPanel);
            app.TestSetUpDropDown.Items = {'no test set ups entered'};
            app.TestSetUpDropDown.ValueChangedFcn = createCallbackFcn(app, @readSetUp_app, true);
            app.TestSetUpDropDown.Value = 'no test set ups entered';
            app.AdditionalNotesTextAreaLabel = uilabel(app.graphPanel);
            app.AdditionalNotesTextAreaLabel.Text = 'Additional Notes:';
            app.AdditionalNotesTextArea = uitextarea(app.graphPanel);
            app.BrowseFIlesButton = uibutton(app.graphPanel, 'push');
            app.BrowseFIlesButton.ButtonPushedFcn = createCallbackFcn(app, @browseFile, true);
            app.BrowseFIlesButton.Text = 'Browse FIles';
            app.setUpsButtons = uipanel(app.AFTFDataPostProcesserUIFigure);
            app.AddNewTestSetUpButton = uibutton(app.setUpsButtons, 'push');
            app.AddNewTestSetUpButton.ButtonPushedFcn = createCallbackFcn(app, @showTestSetUpPanel, true);
            app.AddNewTestSetUpButton.Text = 'Add New Test Set Up';
            app.DeleteSelectedTestSetUpButton = uibutton(app.setUpsButtons, 'push');
            app.DeleteSelectedTestSetUpButton.ButtonPushedFcn = createCallbackFcn(app, @deleteTestSetUp_app, true);
            app.DeleteSelectedTestSetUpButton.Text = 'Delete Selected Test Set Up';
            app.EditSelectedTestSetUpButton = uibutton(app.setUpsButtons, 'push');
            app.EditSelectedTestSetUpButton.ButtonPushedFcn = createCallbackFcn(app, @showEditPanel, true);
            app.EditSelectedTestSetUpButton.Text = 'Edit Selected Test Set Up';
            app.addTestSetUpPanel = uitabgroup(app.AFTFDataPostProcesserUIFigure);
            app.addTestSetUpPanel.Visible = 'off';
            app.TestDimensionsTab = uitab(app.addTestSetUpPanel);
            app.TestDimensionsTab.Title = 'Test Dimensions';
            app.FanTypeNameEditField_2Label = uilabel(app.TestDimensionsTab);
            app.FanTypeNameEditField_2Label.Text = 'Fan Type/ Name';
            app.FanTypeNameEditField = uieditfield(app.TestDimensionsTab, 'text');
            app.TransitionDuctingOutletLengthainEditField_2Label = uilabel(app.TestDimensionsTab);
            app.TransitionDuctingOutletLengthainEditField_2Label.Text = 'Transition Ducting Outlet Length - a (in)';
            app.TransitionDuctingOutletLengthainEditField = uieditfield(app.TestDimensionsTab, 'numeric');
            app.TransitionDuctingOutletWidthbinEditField_2Label = uilabel(app.TestDimensionsTab);
            app.TransitionDuctingOutletWidthbinEditField_2Label.Text = 'Transition Ducting Outlet Width - b (in)';
            app.TransitionDuctingOutletWidthbinEditField = uieditfield(app.TestDimensionsTab, 'numeric');
            app.EnterButton = uibutton(app.TestDimensionsTab, 'push');
            app.EnterButton.ButtonPushedFcn = createCallbackFcn(app, @addNew, true);
            app.EnterButton.Text = 'Enter';
            app.XButton_2 = uibutton(app.TestDimensionsTab, 'push');
            app.XButton_2.ButtonPushedFcn = createCallbackFcn(app, @hideTestSetUp, true);
            app.XButton_2.Text = 'X';
            app.TextArea_2 = uitextarea(app.TestDimensionsTab);
            app.TextArea_2.Editable = 'off';
            app.TextArea_2.Value = {'Select nozzle dimensions on next tab'};
            app.ChamberDiameterD5inEditFieldLabel = uilabel(app.TestDimensionsTab);
            app.ChamberDiameterD5inEditFieldLabel.Text = 'Chamber Diameter - D5 (in)';
            app.ChamberDiameterD5inEditField = uieditfield(app.TestDimensionsTab, 'numeric');
            app.Station6NozzleTypeEditField_2Label = uilabel(app.TestDimensionsTab);
            app.Station6NozzleTypeEditField_2Label.Text = 'Station 6 Nozzle Type';
            app.Station6NozzleTypeEditField = uieditfield(app.TestDimensionsTab, 'text');
            app.KnownFaultySensorDataandAdditionalNotesTextArea_2Label = uilabel(app.TestDimensionsTab);
            app.KnownFaultySensorDataandAdditionalNotesTextArea_2Label.Text = 'Known Faulty Sensor Data and Additional Notes: ';
            app.KnownFaultySensorDataandAdditionalNotesTextArea = uitextarea(app.TestDimensionsTab);
            app.TestPersonnelLabel = uilabel(app.TestDimensionsTab);
            app.TestPersonnelLabel.Text = 'Test Personnel:';
            app.TestPersonnelTextArea = uitextarea(app.TestDimensionsTab);
            app.TestFanTransitionDuctingTypeDimensionsTextArea_2Label = uilabel(app.TestDimensionsTab);
            app.TestFanTransitionDuctingTypeDimensionsTextArea_2Label.Text = 'Test Fan Transition Ducting Type & Dimensions:';
            app.TestFanTransitionDuctingTypeDimensionsTextArea = uitextarea(app.TestDimensionsTab);
            app.TestUnitEditFieldLabel = uilabel(app.TestDimensionsTab);
            app.TestUnitEditFieldLabel.Text = 'Test Unit';
            app.TestUnitEditField = uieditfield(app.TestDimensionsTab, 'text');
            app.NozzleLDRatioDropDownLabel = uilabel(app.TestDimensionsTab);
            app.NozzleLDRatioDropDownLabel.Text = 'Nozzle L/D Ratio:';
            app.NozzleLDRatioDropDown = uidropdown(app.TestDimensionsTab);
            app.NozzleLDRatioDropDown.Items = {'0.5', '0.6'};
            app.NozzleLDRatioDropDown.Value = '0.5';
            app.NozzleSetUpTab = uitab(app.addTestSetUpPanel);
            app.NozzleSetUpTab.Title = 'Nozzle Set Up';
            app.backgroundnozzle = uilamp(app.NozzleSetUpTab);
            app.nozzle1 = uilamp(app.NozzleSetUpTab);
            app.nozzle2 = uilamp(app.NozzleSetUpTab);
            app.nozzle3 = uilamp(app.NozzleSetUpTab);
            app.nozzle4 = uilamp(app.NozzleSetUpTab);
            app.nozzle5 = uilamp(app.NozzleSetUpTab);
            app.nozzle6 = uilamp(app.NozzleSetUpTab);
            app.nozzle7 = uilamp(app.NozzleSetUpTab);
            app.nozzle8 = uilamp(app.NozzleSetUpTab);
            app.nozzle9 = uilamp(app.NozzleSetUpTab);
            app.Nozzle1DiameterDropDown = uidropdown(app.NozzleSetUpTab);
            app.Nozzle1DiameterDropDown.Items = {'3.0000', '4.0000', '5.0000', '6.0004', '7.0000', '8.0013', '8.0072', '9.9580', '10.0043', '12.0008'};
            app.Nozzle1DiameterDropDown.Value = '10.0043';
            app.Nozzle2DiameterDropDown = uidropdown(app.NozzleSetUpTab);
            app.Nozzle2DiameterDropDown.Items = {'Not Used', '3.0000', '4.0000', '5.0000', '6.0004', '7.0000', '8.0013', '8.0072', '9.9580', '10.0043', '12.0008'};
            app.Nozzle2DiameterDropDown.Value = 'Not Used';
            app.Nozzle3DiameterDropDown = uidropdown(app.NozzleSetUpTab);
            app.Nozzle3DiameterDropDown.Items = {'Not Used', '3.0000', '4.0000', '5.0000', '6.0004', '7.0000', '8.0013', '8.0072', '9.9580', '10.0043', '12.0008'};
            app.Nozzle3DiameterDropDown.Value = 'Not Used';
            app.Nozzle4DiameterDropDown = uidropdown(app.NozzleSetUpTab);
            app.Nozzle4DiameterDropDown.Items = {'Not Used', '3.0000', '4.0000', '5.0000', '6.0004', '7.0000', '8.0013', '8.0072', '9.9580', '10.0043', '12.0008'};
            app.Nozzle4DiameterDropDown.Value = 'Not Used';
            app.Nozzle5DiameterDropDown = uidropdown(app.NozzleSetUpTab);
            app.Nozzle5DiameterDropDown.Items = {'Not Used', '3.0000', '4.0000', '5.0000', '6.0004', '7.0000', '8.0013', '8.0072', '9.9580', '10.0043', '12.0008'};
            app.Nozzle5DiameterDropDown.Value = 'Not Used';
            app.Nozzle6DiameterDropDown = uidropdown(app.NozzleSetUpTab);
            app.Nozzle6DiameterDropDown.Items = {'Not Used', '3.0000', '4.0000', '5.0000', '6.0004', '7.0000', '8.0013', '8.0072', '9.9580', '10.0043', '12.0008'};
            app.Nozzle6DiameterDropDown.Value = 'Not Used';
            app.Nozzle7DiameterDropDown = uidropdown(app.NozzleSetUpTab);
            app.Nozzle7DiameterDropDown.Items = {'Not Used', '3.0000', '4.0000', '5.0000', '6.0004', '7.0000', '8.0013', '8.0072', '9.9580', '10.0043', '12.0008'};
            app.Nozzle7DiameterDropDown.Value = 'Not Used';
            app.Nozzle8DiameterDropDown = uidropdown(app.NozzleSetUpTab);
            app.Nozzle8DiameterDropDown.Items = {'Not Used', '3.0000', '4.0000', '5.0000', '6.0004', '7.0000', '8.0013', '8.0072', '9.9580', '10.0043', '12.0008'};
            app.Nozzle8DiameterDropDown.Value = 'Not Used';
            app.Nozzle9DiameterDropDown = uidropdown(app.NozzleSetUpTab);
            app.Nozzle9DiameterDropDown.Items = {'Not Used', '3.0000', '4.0000', '5.0000', '6.0004', '7.0000', '8.0013', '8.0072', '9.9580', '10.0043', '12.0008'};
            app.Nozzle9DiameterDropDown.Value = 'Not Used';
            app.XButton_4 = uibutton(app.NozzleSetUpTab, 'push');
            app.XButton_4.ButtonPushedFcn = createCallbackFcn(app, @hideTestSetUp, true);
            app.XButton_4.Text = 'X';
            app.EnterButton_3 = uibutton(app.NozzleSetUpTab, 'push');
            app.EnterButton_3.ButtonPushedFcn = createCallbackFcn(app, @addNew, true);
            app.EnterButton_3.Text = 'Enter';
            app.editSetUpPanel = uitabgroup(app.AFTFDataPostProcesserUIFigure);
            app.editSetUpPanel.Visible = 'off';
            app.TestDimensionsTab_2 = uitab(app.editSetUpPanel);
            app.TestDimensionsTab_2.Title = 'Test Dimensions';
            app.FanTypeNameEditFieldLabel = uilabel(app.TestDimensionsTab_2);
            app.FanTypeNameEditFieldLabel.Text = 'Fan Type/ Name';
            app.FanTypeNameEditField_2 = uieditfield(app.TestDimensionsTab_2, 'text');
            app.ChamberDiameterD5inEditField_2Label = uilabel(app.TestDimensionsTab_2);
            app.ChamberDiameterD5inEditField_2Label.Text = 'Chamber Diameter - D5 (in)';
            app.ChamberDiameterD5inEditField_2 = uieditfield(app.TestDimensionsTab_2, 'numeric');
            app.TransitionDuctingOutletLengthainEditFieldLabel = uilabel(app.TestDimensionsTab_2);
            app.TransitionDuctingOutletLengthainEditFieldLabel.Text = 'Transition Ducting Outlet Length - a (in)';
            app.TransitionDuctingOutletLengthainEditField_2 = uieditfield(app.TestDimensionsTab_2, 'numeric');
            app.TransitionDuctingOutletWidthbinEditFieldLabel = uilabel(app.TestDimensionsTab_2);
            app.TransitionDuctingOutletWidthbinEditFieldLabel.Text = 'Transition Ducting Outlet Width - b (in)';
            app.TransitionDuctingOutletWidthbinEditField_2 = uieditfield(app.TestDimensionsTab_2, 'numeric');
            app.EnterButton_2 = uibutton(app.TestDimensionsTab_2, 'push');
            app.EnterButton_2.ButtonPushedFcn = createCallbackFcn(app, @editSetUp, true);
            app.EnterButton_2.Text = 'Enter';
            app.XButton_5 = uibutton(app.TestDimensionsTab_2, 'push');
            app.XButton_5.ButtonPushedFcn = createCallbackFcn(app, @hideEditPanel, true);
            app.XButton_5.Text = 'X';
            app.TextArea_3 = uitextarea(app.TestDimensionsTab_2);
            app.TextArea_3.Editable = 'off';
            app.TextArea_3.Value = {'Select nozzle dimensions on next tab'};
            app.Station6NozzleTypeEditFieldLabel = uilabel(app.TestDimensionsTab_2);
            app.Station6NozzleTypeEditFieldLabel.Text = 'Station 6 Nozzle Type';
            app.Station6NozzleTypeEditField_2 = uieditfield(app.TestDimensionsTab_2, 'text');
            app.TestFanTransitionDuctingTypeDimensionsTextAreaLabel = uilabel(app.TestDimensionsTab_2);
            app.TestFanTransitionDuctingTypeDimensionsTextAreaLabel.Text = 'Test Fan Transition Ducting Type & Dimensions:';
            app.TestFanTransitionDuctingTypeDimensionsTextArea_2 = uitextarea(app.TestDimensionsTab_2);
            app.KnownFaultySensorDataandAdditionalNotesTextAreaLabel = uilabel(app.TestDimensionsTab_2);
            app.KnownFaultySensorDataandAdditionalNotesTextAreaLabel.Text = 'Known Faulty Sensor Data and Additional Notes: ';
            app.KnownFaultySensorDataandAdditionalNotesTextArea_2 = uitextarea(app.TestDimensionsTab_2);
            app.NozzleLDRatioDropDown_2Label = uilabel(app.TestDimensionsTab_2);
            app.NozzleLDRatioDropDown_2Label.Text = 'Nozzle L/D Ratio:';
            app.NozzleLDRatioDropDown_2 = uidropdown(app.TestDimensionsTab_2);
            app.NozzleLDRatioDropDown_2.Items = {'0.5', '0.6'};
            app.NozzleLDRatioDropDown_2.Value = '0.5';
            app.TestPersonnelTextArea_2Label = uilabel(app.TestDimensionsTab_2);
            app.TestPersonnelTextArea_2Label.Text = 'Test Personnel:';
            app.TestPersonnelTextArea_2 = uitextarea(app.TestDimensionsTab_2);
            app.TestUnitEditField_2Label = uilabel(app.TestDimensionsTab_2);
            app.TestUnitEditField_2Label.Text = 'Test Unit';
            app.TestUnitEditField_2 = uieditfield(app.TestDimensionsTab_2, 'text');
            app.NozzleSetUpTab_2 = uitab(app.editSetUpPanel);
            app.NozzleSetUpTab_2.Title = 'Nozzle Set Up';
            app.Lamp_11 = uilamp(app.NozzleSetUpTab_2);
            app.Lamp_12 = uilamp(app.NozzleSetUpTab_2);
            app.Lamp_13 = uilamp(app.NozzleSetUpTab_2);
            app.Lamp_14 = uilamp(app.NozzleSetUpTab_2);
            app.Lamp_15 = uilamp(app.NozzleSetUpTab_2);
            app.Lamp_16 = uilamp(app.NozzleSetUpTab_2);
            app.Lamp_17 = uilamp(app.NozzleSetUpTab_2);
            app.Lamp_18 = uilamp(app.NozzleSetUpTab_2);
            app.Lamp_19 = uilamp(app.NozzleSetUpTab_2);
            app.Lamp_20 = uilamp(app.NozzleSetUpTab_2);
            app.Nozzle1DiameterDropDown_2 = uidropdown(app.NozzleSetUpTab_2);
            app.Nozzle1DiameterDropDown_2.Items = {'3', '4', '5', '6.0004', '7', '8.0013', '8.0072', '9.9580', '10.0043', '12.0008'};
            app.Nozzle1DiameterDropDown_2.Value = '10.0043';
            app.Nozzle2DiameterDropDown_2 = uidropdown(app.NozzleSetUpTab_2);
            app.Nozzle2DiameterDropDown_2.Items = {'Not Used', '3', '4', '5', '6.0004', '7', '8.0013', '8.0072', '9.9580', '10.0043', '12.0008'};
            app.Nozzle2DiameterDropDown_2.Value = 'Not Used';
            app.Nozzle3DiameterDropDown_2 = uidropdown(app.NozzleSetUpTab_2);
            app.Nozzle3DiameterDropDown_2.Items = {'Not Used', '3', '4', '5', '6.0004', '7', '8.0013', '8.0072', '9.9580', '10.0043', '12.0008'};
            app.Nozzle3DiameterDropDown_2.Value = 'Not Used';
            app.Nozzle4DiameterDropDown_2 = uidropdown(app.NozzleSetUpTab_2);
            app.Nozzle4DiameterDropDown_2.Items = {'Not Used', '3', '4', '5', '6.0004', '7', '8.0013', '8.0072', '9.9580', '10.0043', '12.0008'};
            app.Nozzle4DiameterDropDown_2.Value = 'Not Used';
            app.Nozzle5DiameterDropDown_2 = uidropdown(app.NozzleSetUpTab_2);
            app.Nozzle5DiameterDropDown_2.Items = {'Not Used', '3', '4', '5', '6.0004', '7', '8.0013', '8.0072', '9.9580', '10.0043', '12.0008'};
            app.Nozzle5DiameterDropDown_2.Value = 'Not Used';
            app.Nozzle6DiameterDropDown_2 = uidropdown(app.NozzleSetUpTab_2);
            app.Nozzle6DiameterDropDown_2.Items = {'Not Used', '3', '4', '5', '6.0004', '7', '8.0013', '8.0072', '9.9580', '10.0043', '12.0008'};
            app.Nozzle6DiameterDropDown_2.Value = 'Not Used';
            app.Nozzle7DiameterDropDown_2 = uidropdown(app.NozzleSetUpTab_2);
            app.Nozzle7DiameterDropDown_2.Items = {'Not Used', '3', '4', '5', '6.0004', '7', '8.0013', '8.0072', '9.9580', '10.0043', '12.0008'};
            app.Nozzle7DiameterDropDown_2.Value = 'Not Used';
            app.Nozzle8DiameterDropDown_2 = uidropdown(app.NozzleSetUpTab_2);
            app.Nozzle8DiameterDropDown_2.Items = {'Not Used', '3', '4', '5', '6.0004', '7', '8.0013', '8.0072', '9.9580', '10.0043', '12.0008'};
            app.Nozzle8DiameterDropDown_2.Value = 'Not Used';
            app.Nozzle9DiameterDropDown_2 = uidropdown(app.NozzleSetUpTab_2);
            app.Nozzle9DiameterDropDown_2.Items = {'Not Used', '3', '4', '5', '6.0004', '7', '8.0013', '8.0072', '9.9580', '10.0043', '12.0008'};
            app.Nozzle9DiameterDropDown_2.Value = 'Not Used';
            app.XButton_6 = uibutton(app.NozzleSetUpTab_2, 'push');
            app.XButton_6.ButtonPushedFcn = createCallbackFcn(app, @hideEditPanel, true);
            app.XButton_6.Text = 'X';
            app.EnterButton_4 = uibutton(app.NozzleSetUpTab_2, 'push');
            app.EnterButton_4.ButtonPushedFcn = createCallbackFcn(app, @editSetUp, true);
            app.EnterButton_4.Text = 'Enter';
        end
    end

    methods (Access = public)

        % Construct app
        function app = AFTF_processing

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AFTFDataPostProcesserUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AFTFDataPostProcesserUIFigure)
        end
    end
end